classdef ImageAcquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImageTab                        matlab.ui.container.Tab
        PauseContinueButton             matlab.ui.control.StateButton
        StopButton                      matlab.ui.control.StateButton
        SaveButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        SettingsTab                     matlab.ui.container.Tab
        AcquisitionSettingsPanel        matlab.ui.container.Panel
        AcquisitionModeDropDown_2       matlab.ui.control.DropDown
        AcquisitionModeDropDown_2Label  matlab.ui.control.Label
        ExposureTimeEditField           matlab.ui.control.NumericEditField
        ExposureTimeEditFieldLabel      matlab.ui.control.Label
        ReadoutModeDropDown             matlab.ui.control.DropDown
        ReadoutModeDropDownLabel        matlab.ui.control.Label
        SelectCameraDropDown            matlab.ui.control.DropDown
        SelectCameraDropDownLabel       matlab.ui.control.Label
        CCDConnectionsPanel             matlab.ui.container.Panel
        SelectCameraforClosingDropDown  matlab.ui.control.DropDown
        SelectCameraforClosingDropDownLabel  matlab.ui.control.Label
        SelectCameraforInitializationDropDown  matlab.ui.control.DropDown
        SelectCameraforInitializationDropDownLabel  matlab.ui.control.Label
        NumberofCCDconnectedLabel       matlab.ui.control.Label
        CloseCCDButton                  matlab.ui.control.Button
        InitializeCCDButton             matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_2Label                     matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        LampLabel                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CurrentImage = 0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupUI(app)
            num_cameras = getNumAndor();

        end

        % Button pushed function: StartButton
        function start(app, event)
            while ~app.StopButton.Value

                while ~app.PauseContinueButton.Value && ~app.StopButton.Value
                    tic
                    fprintf('Acquiring CCD image...\n')
                    
                    image = acquireCCDImage("num_images", 1, "timeout", 30);
                    num_image = num_image + 1;
                    
                    imagesc(app.UIAxes, image)
                    app.UIAxes.DataAspectRatio = [1, 1, 1];
                    app.UIAxes.Title.String = sprintf('Number of image: %d', num_image);
                    drawnow

                    fprintf('Acquisition complete, number of images : %d\n', num_image);
                    pause(0.1)
                    toc
                end

                pause(0.1)
            end

            fprintf('Acquisition stopped\n')
            app.StopButton.Value = false;
        end

        % Button pushed function: InitializeCCDButton
        function initialize_ccd(app, event)
            initializeAndor()
            select_ccd(app, event)
            setDMDLive(0.1)
        end

        % Button pushed function: CloseCCDButton
        function shutdown_ccd(app, event)
            closeCCD()
        end

        % Value changed function: SelectCameraDropDown
        function select_ccd(app, event)
            value = app.SelectCameraDropDown.Value;
            switch value
                case 'Upper CCD: 19330'
                    setCurrentAndor(19330)
                case 'Lower CCD: 19331'
                    setCurrentAndor(19331)
            end
        end

        % Button pushed function: SaveButton
        function save_current(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.UIAxes = uiaxes(app.ImageTab);
            app.UIAxes_2 = uiaxes(app.ImageTab);
            title(app.UIAxes_2, ' ')
            app.StartButton = uibutton(app.ImageTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.ImageTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @save_current, true);
            app.SaveButton.Text = 'Save';
            app.StopButton = uibutton(app.ImageTab, 'state');
            app.StopButton.Text = 'Stop';
            app.PauseContinueButton = uibutton(app.ImageTab, 'state');
            app.PauseContinueButton.Text = 'Pause / Continue';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.CCDConnectionsPanel = uipanel(app.SettingsTab);
            app.CCDConnectionsPanel.Title = 'CCD Connections';
            app.LampLabel = uilabel(app.CCDConnectionsPanel);
            app.LampLabel.Text = '19330';
            app.Lamp = uilamp(app.CCDConnectionsPanel);
            app.Lamp_2Label = uilabel(app.CCDConnectionsPanel);
            app.Lamp_2Label.Text = '19331';
            app.Lamp_2 = uilamp(app.CCDConnectionsPanel);
            app.InitializeCCDButton = uibutton(app.CCDConnectionsPanel, 'push');
            app.InitializeCCDButton.ButtonPushedFcn = createCallbackFcn(app, @initialize_ccd, true);
            app.InitializeCCDButton.Text = 'Initialize CCD';
            app.CloseCCDButton = uibutton(app.CCDConnectionsPanel, 'push');
            app.CloseCCDButton.ButtonPushedFcn = createCallbackFcn(app, @shutdown_ccd, true);
            app.CloseCCDButton.Text = 'Close CCD';
            app.NumberofCCDconnectedLabel = uilabel(app.CCDConnectionsPanel);
            app.NumberofCCDconnectedLabel.Text = 'Number of CCD connected: ';
            app.SelectCameraforInitializationDropDownLabel = uilabel(app.CCDConnectionsPanel);
            app.SelectCameraforInitializationDropDownLabel.Text = 'Select Camera for Initialization';
            app.SelectCameraforInitializationDropDown = uidropdown(app.CCDConnectionsPanel);
            app.SelectCameraforInitializationDropDown.Items = {'19330', '19331', '[19330,19331]'};
            app.SelectCameraforInitializationDropDown.Value = '19330';
            app.SelectCameraforClosingDropDownLabel = uilabel(app.CCDConnectionsPanel);
            app.SelectCameraforClosingDropDownLabel.Text = 'Select Camera for Closing';
            app.SelectCameraforClosingDropDown = uidropdown(app.CCDConnectionsPanel);
            app.SelectCameraforClosingDropDown.Items = {'19330', '19331', '[19330,19331]'};
            app.SelectCameraforClosingDropDown.Value = '19330';
            app.AcquisitionSettingsPanel = uipanel(app.SettingsTab);
            app.AcquisitionSettingsPanel.Title = 'Acquisition Settings';
            app.SelectCameraDropDownLabel = uilabel(app.AcquisitionSettingsPanel);
            app.SelectCameraDropDownLabel.Text = 'Select Camera ';
            app.SelectCameraDropDown = uidropdown(app.AcquisitionSettingsPanel);
            app.SelectCameraDropDown.Items = {'19330', '19331', '[19330, 19331]'};
            app.SelectCameraDropDown.ValueChangedFcn = createCallbackFcn(app, @select_ccd, true);
            app.SelectCameraDropDown.Value = '19330';
            app.ReadoutModeDropDownLabel = uilabel(app.AcquisitionSettingsPanel);
            app.ReadoutModeDropDownLabel.Text = 'Readout Mode';
            app.ReadoutModeDropDown = uidropdown(app.AcquisitionSettingsPanel);
            app.ReadoutModeDropDown.Items = {'19330', '19331', '[19330, 19331]'};
            app.ReadoutModeDropDown.Value = '19330';
            app.ExposureTimeEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.ExposureTimeEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.AcquisitionModeDropDown_2Label = uilabel(app.AcquisitionSettingsPanel);
            app.AcquisitionModeDropDown_2Label.Text = 'Acquisition Mode';
            app.AcquisitionModeDropDown_2 = uidropdown(app.AcquisitionSettingsPanel);
            app.AcquisitionModeDropDown_2.Items = {'19330', '19331', '[19330, 19331]'};
            app.AcquisitionModeDropDown_2.Value = '19330';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageAcquisition

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupUI)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end